classdef ABSPOS__LAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ABSPOS_LAB           matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        SiteDropDownLabel    matlab.ui.control.Label
        SiteDropDown         matlab.ui.control.DropDown
        DateDatePickerLabel  matlab.ui.control.Label
        DateDatePicker       matlab.ui.control.DatePicker
        CALCULATEButton      matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        XLabel               matlab.ui.control.Label
        YLabel               matlab.ui.control.Label
        ZLabel               matlab.ui.control.Label
        Panel_3              matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes6              matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             ss = get(0,'screensize');
%             app.ABSPOS_LAB.Position = [0 0 ss(3) ss(4)];
            drawnow;
            app.ABSPOS_LAB.WindowState = 'maximized';
            app.DateDatePicker.Limits = [datetime('01/01/1980'),datetime('yesterday')];
            
            IGSstations = readtable('IGSNetwork.csv');
            app.SiteDropDown.Items = IGSstations.x_StationName;
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            station = app.SiteDropDown.Value;
            date = app.DateDatePicker.Value;
            d = uiprogressdlg(app.ABSPOS_LAB,'Title','ABSPOS_LAB','Indeterminate','on');
            drawnow
            d.Message = "The daily rinex file downloading...";
            obsfile = getrinexfiles(datestr(date,'yyyy-mm-dd'),station);
            d.Message = "The daily broadcast file downloading...";
            navfile = getnavfiles(datestr(date,'yyyy-mm-dd'));
            
            % read observation file
            d.Message = "The rinex file reading...";
            
            [fID] = fopen(obsfile, 'r');
            if fID < 0
                error('Unable to open file named %s.\n\n', obsfile);
            end
            lines = fgets(fID);
            rinexver = str2double(lines(1:15));
            if rinexver > 2
                obsfile = strrep(obsfile,'crx','rnx');
            elseif rinexver < 3 && strcmp(obsfile(end),'d')
                obsfile(end) = 'o';
            end
            [obsmat] = rinexversion(string(obsfile));
            if rinexver > 2
                obsmat.info.typeofobs = obsmat.obs(2,:)';
            end
            rec_xyz = obsmat.info.approxpos';
            % define cols
            obs.col.WEEK = 1;
            obs.col.TOW = 2;
            obs.col.PRN = 3;
            col_offset = 3;
            for ii=1:length(obsmat.info.typeofobs)
                eval(sprintf('obs.col.%s=%s;',obsmat.info.typeofobs{ii},num2str(ii+col_offset)))
            end
            % create obs.data
            [gpswk,gpssec] = cal2gpstime(obsmat.info.epochs);
            for i = length(obsmat.info.epochs):-1:1
                obsmat.info.satno{i}(find(obsmat.info.satno{i}>32)) = []; %% SADECE GPS UYDULARI
                obsdata{i,1} = repmat(gpswk(i),length(obsmat.info.satno{i}),1);
                obsdata{i,2} = repmat(gpssec(i),length(obsmat.info.satno{i}),1);
                obsdata{i,3} = obsmat.info.satno{i};
                for j = 1 : length(obsmat.info.typeofobs)
                    if sum(strcmp(obsmat.info.typeofobs{j},obsmat.obs(2,:))) > 0
                        obsdata{i,col_offset+j} = obsmat.obs{1,find(strcmp(obsmat.info.typeofobs{j},obsmat.obs(2,:)))}(i,obsmat.info.satno{i})';
                    else
                        obsdata{i,col_offset+j} = NaN(length(obsmat.info.satno{i}),1);
                    end
                end
            end
            obs.data = reshape(vertcat(obsdata{:}),length(vertcat(obsmat.info.satno{:})),length(fieldnames(obs.col))); clear obsdata;
            obs.data(any(isnan(obs.data(:,obs.col.C1)), 2), :) = [];
            obs.data(any(isnan(obs.data(:,obs.col.P2)), 2), :) = [];
            
            % read navigation file
            d.Message = "The broadcast file reading...";
            ephemeris = read_rinex_nav(string(navfile));
            
            epochs = unique(obs.data(:, obs.col.TOW));
            TimeSpan=epochs(1:length(epochs));
            
            % Broadcast Orbit
            satOrbits.XS=zeros(1,length(TimeSpan));
            satOrbits.YS=zeros(1,length(TimeSpan));
            satOrbits.ZS=zeros(1,length(TimeSpan));
            satOrbits.VXS=zeros(1,length(TimeSpan));
            satOrbits.VYS=zeros(1,length(TimeSpan));
            satOrbits.VZS=zeros(1,length(TimeSpan));
            satOrbits.clk=zeros(1,length(TimeSpan));
            satOrbits.Rel=zeros(1,length(TimeSpan));
            
            % GPS Satellite Measurements
            c = 2.99792458e8 ; % speed of light (m/s)
            fL1 = 1575.42e6;   % L1 frequency (Hz)
            fL2 = 1227.6e6;    % L2 frequency (Hz)
            B=fL2^2/(fL2^2-fL1^2);
            A=-B+1;
            satOrbits.C1=zeros(1,length(TimeSpan));
            satOrbits.P2=zeros(1,length(TimeSpan));
            satOrbits.P3=zeros(1,length(TimeSpan)); % Iono free pseudorange
            satOrbits.CorrP1=zeros(1,length(TimeSpan)); % Corrected Pseudorange from broadcast orbit
            satOrbits.TOW=TimeSpan';
            satOrbits.PRN=0;
           
            satOrbits = repmat(satOrbits,1,32); 
            for ii=1:32
                satOrbits(ii).PRN=ii;
            end
             
            % Initialize User Position
            userPos=zeros(length(TimeSpan),4);
            for ii = 1 : length(TimeSpan)
                this_TOW = TimeSpan(ii);
                index = find(obs.data(:,obs.col.TOW) == this_TOW);
                curr_obs.data = obs.data(index, :);
                curr_obs.col = obs.col;
                
                for jj=1:size(curr_obs.data,1)        
                    PRN_obs.data = curr_obs.data(jj,:);
                    PRN_obs.col = curr_obs.col;
                    % Record Measurements
                    satOrbits(PRN_obs.data(PRN_obs.col.PRN)).C1(ii)=PRN_obs.data(PRN_obs.col.C1);
                    satOrbits(PRN_obs.data(PRN_obs.col.PRN)).P2(ii)=PRN_obs.data(PRN_obs.col.P2);
                    % Calculate Iono Free Measurement
                    P1 = satOrbits(PRN_obs.data(PRN_obs.col.PRN)).C1(ii);
                    P2 = satOrbits(PRN_obs.data(PRN_obs.col.PRN)).P2(ii);
                    P3=A*P1+B*P2;
                    satOrbits(PRN_obs.data(PRN_obs.col.PRN)).P3(ii)=P3;
                end
                    

                    for jj=1:size(curr_obs.data,1)
                        PRN_obs.data = curr_obs.data(jj,:);
                        PRN_obs.col = curr_obs.col;
                        
                        % Obtain the broadcast orbits 
                        PRN_obs = get_broadcast_orbits(PRN_obs,ephemeris,rec_xyz');
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).XS(ii)=PRN_obs.data(PRN_obs.col.XS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).YS(ii)=PRN_obs.data(PRN_obs.col.YS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).ZS(ii)=PRN_obs.data(PRN_obs.col.ZS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).VXS(ii)=PRN_obs.data(PRN_obs.col.VXS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).VYS(ii)=PRN_obs.data(PRN_obs.col.VYS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).VZS(ii)=PRN_obs.data(PRN_obs.col.VZS);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).clk(ii)=PRN_obs.data(PRN_obs.col.satClkCorr);
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).Rel(ii)=PRN_obs.data(PRN_obs.col.Rel);            
                        
                        % Calculate corrected pseudorange based on broadcast orbit
                        satOrbits(PRN_obs.data(PRN_obs.col.PRN)).CorrP1(ii)=...
                            satOrbits(PRN_obs.data(PRN_obs.col.PRN)).P3(ii)+...
                            satOrbits(PRN_obs.data(PRN_obs.col.PRN)).clk(ii)+satOrbits(PRN_obs.data(PRN_obs.col.PRN)).Rel(ii);            
                    end
% %                     
                    
                    % Calculate User Position
                    d.Message = "Calculating User Position...";
                    [broadcast_obs,~]=createObs(this_TOW,satOrbits);
                    [delta_xyz, mo, PDOP] = comp_pos(broadcast_obs,rec_xyz');
                    rec_xyz = rec_xyz + delta_xyz(1:3);
                    
                userPos(ii,1:10) = [rec_xyz; delta_xyz(4); delta_xyz(1:3); mo; size(curr_obs.data,1); PDOP]';
                [lat(ii),lon(ii),h(ii)] = ecef2geodetic(wgs84Ellipsoid('meter'),rec_xyz(1),rec_xyz(2),rec_xyz(3));
            end
            
            results.recpos = [userPos lat' lon' h'];    
            app.XLabel.Text = ['X : ',num2str(mean(userPos(:,1)),'%10.3f m '),char(177),num2str(std(userPos(:,1)),' %5.1f m')];
            app.YLabel.Text = ['Y : ',num2str(mean(userPos(:,2)),'%10.3f m '),char(177),num2str(std(userPos(:,2)),' %5.1f m')];
            app.ZLabel.Text = ['Z : ',num2str(mean(userPos(:,3)),'%10.3f m '),char(177),num2str(std(userPos(:,3)),' %5.1f m')];
            
            
            g = plot(app.UIAxes,1:length(results.recpos(:,1)),(mean(userPos(:,1)))-results.recpos(:,1),'color','black');
                app.UIAxes.XLim=[0 length(results.recpos(:,1))];
                app.UIAxes.XTick=[0:480:length(results.recpos(:,1))];
                app.UIAxes.XTickLabel=[0:4:24];
                
            g1 = plot(app.UIAxes2,1:length(results.recpos(:,2)),(mean(userPos(:,2)))-results.recpos(:,2),'color','black');
                app.UIAxes2.XLim=[0 length(results.recpos(:,2))];
                app.UIAxes2.XTick=[0:480:length(results.recpos(:,2))];
                app.UIAxes2.XTickLabel=[0:4:24];
                
            g2 = plot(app.UIAxes3,1:length(results.recpos(:,3)),(mean(userPos(:,3)))-results.recpos(:,3),'color','black');
                app.UIAxes3.XLim=[0 length(results.recpos(:,3))];
                app.UIAxes3.XTick=[0:480:length(results.recpos(:,3))];
                app.UIAxes3.XTickLabel=[0:4:24];
                
            g3 = bar(app.UIAxes4,1:length(results.recpos(:,8)),results.recpos(:,8),1,'black');
                app.UIAxes4.XLim=[0 length(results.recpos(:,8))];
                app.UIAxes4.XTick=[0:480:length(results.recpos(:,8))];
                app.UIAxes4.XTickLabel=[0:4:24];

            g4 = bar(app.UIAxes5,1:length(results.recpos(:,9)),results.recpos(:,9),1,'black');
                app.UIAxes5.XLim=[0 length(results.recpos(:,9))];
                app.UIAxes5.XTick=[0:480:length(results.recpos(:,9))];
                app.UIAxes5.XTickLabel=[0:4:24];
            
            g5 = bar(app.UIAxes6,1:length(results.recpos(:,10)),results.recpos(:,10),1,'black');
                app.UIAxes6.XLim=[0 length(results.recpos(:,10))];
                app.UIAxes6.XTick=[0:480:length(results.recpos(:,10))];
                app.UIAxes6.XTickLabel=[0:4:24];
            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ABSPOS_LAB = uifigure('Visible', 'off');
            app.ABSPOS_LAB.Name = 'ABSPOS';
            app.Panel = uipanel(app.ABSPOS_LAB);
            app.SiteDropDownLabel = uilabel(app.Panel);
            app.SiteDropDownLabel.Text = 'Site';
            app.SiteDropDown = uidropdown(app.Panel);
            app.SiteDropDown.Editable = 'on';
            app.DateDatePickerLabel = uilabel(app.Panel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.Panel);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.CALCULATEButton = uibutton(app.Panel, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.Panel_2 = uipanel(app.ABSPOS_LAB);
            app.XLabel = uilabel(app.Panel_2);
            app.XLabel.Text = 'X : ';
            app.YLabel = uilabel(app.Panel_2);
            app.YLabel.Text = 'Y :';
            app.ZLabel = uilabel(app.Panel_2);
            app.ZLabel.Text = 'Z : ';
            app.Panel_3 = uipanel(app.ABSPOS_LAB);
            app.UIAxes = uiaxes(app.Panel_3);
            title(app.UIAxes, 'X_{PosError}')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, 'Y_{PosError}')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes3 = uiaxes(app.Panel_3);
            title(app.UIAxes3, 'Z_{PosError}')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes4 = uiaxes(app.Panel_3);
            title(app.UIAxes4, 'Std dev')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes5 = uiaxes(app.Panel_3);
            title(app.UIAxes5, 'Sat count')
            xlabel(app.UIAxes5, '')
            ylabel(app.UIAxes5, '')
            app.UIAxes6 = uiaxes(app.Panel_3);
            title(app.UIAxes6, 'PDOP')
            xlabel(app.UIAxes6, '')
            ylabel(app.UIAxes6, '')
            % Show the figure after all components are created
            app.ABSPOS_LAB.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ABSPOS__LAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ABSPOS_LAB)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ABSPOS_LAB)
        end
    end
end